classdef Scint_City < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        pairsEditField               matlab.ui.control.NumericEditField
        dzNzEditFieldLabel           matlab.ui.control.Label
        dzNzEditField                matlab.ui.control.NumericEditField
        RunScintCityButton           matlab.ui.control.Button
        thetaEditFieldLabel          matlab.ui.control.Label
        n_substrateEditField         matlab.ui.control.NumericEditField
        n_scintEditField             matlab.ui.control.NumericEditField
        thetaEditField               matlab.ui.control.EditField
        YmuEditField                 matlab.ui.control.NumericEditField
        YsigmaEditField              matlab.ui.control.NumericEditField
        n_otherEditField             matlab.ui.control.NumericEditField
        pairsEditFieldLabel          matlab.ui.control.Label
        n_substrateEditFieldLabel    matlab.ui.control.Label
        lambdaEditField              matlab.ui.control.EditField
        centrallambdaEditField       matlab.ui.control.NumericEditField
        n_scintEditField_2Label      matlab.ui.control.Label
        YmuEditFieldLabel            matlab.ui.control.Label
        YsigmaEditFieldLabel         matlab.ui.control.Label
        n_otherEditField_2Label      matlab.ui.control.Label
        lambdaEditFieldLabel         matlab.ui.control.Label
        centrallambdaEditFieldLabel  matlab.ui.control.Label
        dirnameEditFieldLabel        matlab.ui.control.Label
        dirnameEditField             matlab.ui.control.EditField
        OptimizeSwitchLabel          matlab.ui.control.Label
        OptimizeSwitch               matlab.ui.control.Switch
        SavefigsSwitchLabel          matlab.ui.control.Label
        SavefigsSwitch               matlab.ui.control.Switch
        KnownstructurePanel          matlab.ui.container.Panel
        dEditField                   matlab.ui.control.EditField
        dEditFieldLabel              matlab.ui.control.Label
        nEditFieldLabel              matlab.ui.control.Label
        nEditField                   matlab.ui.control.EditField
        scintindexLabel              matlab.ui.control.Label
        scintindexEditField          matlab.ui.control.EditField
        GzSwitchLabel                matlab.ui.control.Label
        GzSwitch                     matlab.ui.control.Switch
        numiterationsEditField       matlab.ui.control.NumericEditField
        numiterationsEditFieldLabel  matlab.ui.control.Label
        totalsizeconEditFieldLabel   matlab.ui.control.Label
        totalsizeconEditField        matlab.ui.control.NumericEditField
        TotalsizeconLabel            matlab.ui.control.Label
        TotalsizeconSwitch           matlab.ui.control.Switch
    end


    methods (Access = private)

        % Button pushed function: RunScintCityButton
        function RunScintCityButtonPushed(app, event)
            
            theta_vec = str2double(strsplit(app.thetaEditField.Value));
            if length(theta_vec) == 1
               theta = theta_vec(1); 
            else
               theta = linspace(theta_vec(1), theta_vec(2), theta_vec(3)); 
            end
            
            lambda_vec = str2double(strsplit(app.lambdaEditField.Value));
            if length(lambda_vec) == 1
               lambda = lambda_vec(1) * 1e-9; 
            else
               lambda = linspace(lambda_vec(1) * 1e-9, lambda_vec(2) * 1e-9, lambda_vec(3)); 
            end
            
            if isempty(app.dEditField.Value)
                d = [];
            else
                d = str2double(strsplit(app.dEditField.Value)) * 1e-9;
            end
            
            if isempty(app.nEditField.Value)
                n = [];
            else
                n = str2double(strsplit(app.nEditField.Value));
            end
            
            if isempty(app.scintindexEditField.Value)
                i_scint = [];
            else
                i_scint = str2double(strsplit(app.scintindexEditField.Value));
            end
            
            if app.GzSwitch.Value == "dz"
                is_Gz = false;
                dz_Nz = app.dzNzEditField.Value * 1e-9;
            else
                is_Gz = true;
                dz_Nz = app.dzNzEditField.Value;
            end
            
            if app.TotalsizeconSwitch.Value == "On"
                total_size_con = app.totalsizeconEditField.Value * 1e-9;
            else
                total_size_con = 0;
            end
            
            central_lambda  = app.centrallambdaEditField.Value * 1e-9;
            mu_lambda       = app.YmuEditField.Value * 1e-9;
            sigma_lambda    = app.YsigmaEditField.Value * 1e-9;
            pairs           = app.pairsEditField.Value;
            n_si            = app.n_substrateEditField.Value;
            n_scint         = app.n_scintEditField.Value;
            n_ox            = app.n_otherEditField.Value;
            num_iterations  = app.numiterationsEditField.Value;
            optimize        = app.OptimizeSwitch.Value ~= "Known Structure";
            save_fig        = app.SavefigsSwitch.Value == "On";
            dir_name        = app.dirnameEditField.Value;
            
            One_D_PhC_Optimization( dz_Nz,             ...
                                    theta,          ...
                                    lambda,         ...
                                    central_lambda, ...
                                    mu_lambda,      ...
                                    sigma_lambda,   ...
                                    pairs,          ...
                                    n_si,           ...
                                    n_scint,        ...
                                    n_ox,           ...
                                    d,              ...
                                    n,              ...
                                    i_scint,        ...
                                    is_Gz,          ...
                                    num_iterations, ...
                                    total_size_con, ...
                                    optimize,       ...
                                    save_fig,       ...
                                    dir_name );
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.pairsEditField = uieditfield(app.UIFigure, 'numeric');
            app.pairsEditField.Value = 4;
            app.dzNzEditFieldLabel = uilabel(app.UIFigure);
            app.dzNzEditFieldLabel.Text = 'dz/Nz';
            app.dzNzEditField = uieditfield(app.UIFigure, 'numeric');
            app.dzNzEditField.Value = 10;
            app.RunScintCityButton = uibutton(app.UIFigure, 'push');
            app.RunScintCityButton.ButtonPushedFcn = createCallbackFcn(app, @RunScintCityButtonPushed, true);
            app.RunScintCityButton.Text = 'Run Scint-City';
            app.thetaEditFieldLabel = uilabel(app.UIFigure);
            app.thetaEditFieldLabel.Text = 'theta';
            app.n_substrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.n_substrateEditField.Value = 4.3;
            app.n_scintEditField = uieditfield(app.UIFigure, 'numeric');
            app.n_scintEditField.Value = 1.6;
            app.thetaEditField = uieditfield(app.UIFigure, 'text');
            app.thetaEditField.Value = '0';
            app.YmuEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmuEditField.Value = 430;
            app.YsigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.n_otherEditField = uieditfield(app.UIFigure, 'numeric');
            app.n_otherEditField.Value = 2.1;
            app.pairsEditFieldLabel = uilabel(app.UIFigure);
            app.pairsEditFieldLabel.Text = 'pairs';
            app.n_substrateEditFieldLabel = uilabel(app.UIFigure);
            app.n_substrateEditFieldLabel.Text = 'n_substrate';
            app.lambdaEditField = uieditfield(app.UIFigure, 'text');
            app.lambdaEditField.Value = '410 450 301';
            app.centrallambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.centrallambdaEditField.Value = 430;
            app.n_scintEditField_2Label = uilabel(app.UIFigure);
            app.n_scintEditField_2Label.Text = 'n_scint';
            app.YmuEditFieldLabel = uilabel(app.UIFigure);
            app.YmuEditFieldLabel.Text = 'Y mu';
            app.YsigmaEditFieldLabel = uilabel(app.UIFigure);
            app.YsigmaEditFieldLabel.Text = 'Y sigma';
            app.n_otherEditField_2Label = uilabel(app.UIFigure);
            app.n_otherEditField_2Label.Text = 'n_other';
            app.lambdaEditFieldLabel = uilabel(app.UIFigure);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.centrallambdaEditFieldLabel = uilabel(app.UIFigure);
            app.centrallambdaEditFieldLabel.Text = 'central lambda';
            app.dirnameEditFieldLabel = uilabel(app.UIFigure);
            app.dirnameEditFieldLabel.Text = 'dir name';
            app.dirnameEditField = uieditfield(app.UIFigure, 'text');
            app.dirnameEditField.Value = 'Results';
            app.OptimizeSwitchLabel = uilabel(app.UIFigure);
            app.OptimizeSwitchLabel.Text = 'Optimize';
            app.OptimizeSwitch = uiswitch(app.UIFigure, 'slider');
            app.OptimizeSwitch.Items = {'', 'Known Structure'};
            app.OptimizeSwitch.Value = '';
            app.SavefigsSwitchLabel = uilabel(app.UIFigure);
            app.SavefigsSwitchLabel.Text = 'Save figs';
            app.SavefigsSwitch = uiswitch(app.UIFigure, 'slider');
            app.KnownstructurePanel = uipanel(app.UIFigure);
            app.KnownstructurePanel.Title = 'Known structure';
            app.dEditField = uieditfield(app.KnownstructurePanel, 'text');
            app.dEditFieldLabel = uilabel(app.KnownstructurePanel);
            app.dEditFieldLabel.Text = 'd';
            app.nEditFieldLabel = uilabel(app.KnownstructurePanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.KnownstructurePanel, 'text');
            app.scintindexLabel = uilabel(app.KnownstructurePanel);
            app.scintindexLabel.Text = 'scint index';
            app.scintindexEditField = uieditfield(app.KnownstructurePanel, 'text');
            app.GzSwitchLabel = uilabel(app.UIFigure);
            app.GzSwitchLabel.Text = 'G(z)';
            app.GzSwitch = uiswitch(app.UIFigure, 'slider');
            app.GzSwitch.Items = {'', 'dz'};
            app.GzSwitch.Value = '';
            app.numiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.numiterationsEditField.Value = 1;
            app.numiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.numiterationsEditFieldLabel.Text = 'num iterations';
            app.totalsizeconEditFieldLabel = uilabel(app.UIFigure);
            app.totalsizeconEditFieldLabel.Text = 'total size con';
            app.totalsizeconEditField = uieditfield(app.UIFigure, 'numeric');
            app.totalsizeconEditField.Value = 1000;
            app.TotalsizeconLabel = uilabel(app.UIFigure);
            app.TotalsizeconLabel.Text = 'Total size con.';
            app.TotalsizeconSwitch = uiswitch(app.UIFigure, 'slider');
            app.TotalsizeconSwitch.Items = {'On', 'Off'};
            app.TotalsizeconSwitch.Value = 'On';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Scint_City

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end